classdef EE305_Group7_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ProcessButton                 matlab.ui.control.Button
        OutputButtonGroup             matlab.ui.container.ButtonGroup
        ShowAntennaButton             matlab.ui.control.Button
        HPBWButton                    matlab.ui.control.Button
        VSWRButton                    matlab.ui.control.Button
        ShowElevationPatternButton    matlab.ui.control.Button
        ShowRadiationPatternButton    matlab.ui.control.Button
        EHfieldButton                 matlab.ui.control.Button
        ShowAzimuthPatternButton      matlab.ui.control.Button
        InputButtonGroup              matlab.ui.container.ButtonGroup
        DropDown_3                    matlab.ui.control.DropDown
        EndFrequencyEditField         matlab.ui.control.NumericEditField
        EndFrequencyEditFieldLabel    matlab.ui.control.Label
        DropDown_2                    matlab.ui.control.DropDown
        StartFrequencyEditField       matlab.ui.control.NumericEditField
        StartFrequencyEditFieldLabel  matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        FrequencyEditField            matlab.ui.control.NumericEditField
        FrequencyEditFieldLabel       matlab.ui.control.Label
        WidthinmmEditField            matlab.ui.control.NumericEditField
        WidthinmmEditFieldLabel       matlab.ui.control.Label
        LengthincmEditField           matlab.ui.control.NumericEditField
        LengthincmEditFieldLabel      matlab.ui.control.Label
        TYPEOFANTENNAButtonGroup      matlab.ui.container.ButtonGroup
        RectangularLoopButton         matlab.ui.control.ToggleButton
        DipoleVeeButton               matlab.ui.control.ToggleButton
        CylindricalAntennaButton      matlab.ui.control.ToggleButton
        DipoleAntennaButton           matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        f
        L
        W
        d
        sf
        ef
        stepf
        toggle
    end


    methods (Access = private)
        
        function results = getorder(app,k)
            if strcmpi(k,'Hz')
                results=1;
            elseif strcmpi(k,'KHz')
                results=1000;
            elseif strcmpi(k,'MHz')
                results=1e6;
            else
                results=1e9;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ProcessButton
        function ProcessButtonPushed(app, event)

            order=getorder(app,app.DropDown.Value);
            app.f=app.FrequencyEditField.Value*order;
            order=getorder(app,app.DropDown_2.Value);
            app.sf=app.StartFrequencyEditField.Value*order;
            order=getorder(app,app.DropDown_3.Value);
            app.ef=app.EndFrequencyEditField.Value*order;
            app.stepf=(app.ef-app.sf)./100;
            app.L=app.LengthincmEditField.Value*0.01;
            app.W=app.WidthinmmEditField.Value*0.001;
            if(app.DipoleAntennaButton.Value==1)
            app.d = dipole('Length',app.L,'Width',app.W,'TiltAxis','Z');
            elseif(app.CylindricalAntennaButton.Value==1)
            app.d = dipoleCylindrical('Length',app.L,'Radius',app.W,'TiltAxis','Z');
            elseif(app.RectangularLoopButton.Value==1)
            app.d= loopRectangular('Length',app.L,'Width',app.W,'Thickness',0.00001);
            else
            app.d = dipoleVee('ArmLength',[app.L,app.L],'Width',app.W);   
            end
        end

        % Button pushed function: ShowRadiationPatternButton
        function ShowRadiationPatternButtonPushed(app, event)
            figure,pattern(app.d,app.f),title('3D Radiation Pattern of dipole with L=3\lambda/4')
        end

        % Button pushed function: ShowElevationPatternButton
        function ShowElevationPatternButtonPushed(app, event)
            figure,patternElevation(app.d,app.f), %title('E-Plane'),xlabel('E-Plane'),ylabel('E-Plane')
        end

        % Button pushed function: ShowAzimuthPatternButton
        function ShowAzimuthPatternButtonPushed(app, event)
            figure,patternAzimuth(app.d,app.f),%title('H-Plane'),xlabel('H-Plane')
        end

        % Button pushed function: HPBWButton
        function HPBWButtonPushed(app, event)
            figure,beamwidth(app.d,app.f,0,1:1:360);title('HPBW')%Finding HPBW
        end

        % Callback function
        function EfficiencyButtonPushed(app, event)
        
            efficiency(app.d,linspace(app.sf,app.ef,41));
        end

        % Button pushed function: VSWRButton
        function VSWRButtonPushed(app, event)
            vswr(app.d,app.sf:app.stepf:app.ef,50)
        end

        % Button pushed function: EHfieldButton
        function EHfieldButtonPushed(app, event)
            EHfields(app.d,app.f)
        end

        % Button pushed function: ShowAntennaButton
        function ShowAntennaButtonPushed(app, event)
            show(app.d);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TYPEOFANTENNAButtonGroup = uibuttongroup(app.UIFigure);
            app.TYPEOFANTENNAButtonGroup.Title = 'TYPE OF ANTENNA';
            app.DipoleAntennaButton = uitogglebutton(app.TYPEOFANTENNAButtonGroup);
            app.DipoleAntennaButton.Text = 'Dipole Antenna';
            app.DipoleAntennaButton.Value = true;
            app.CylindricalAntennaButton = uitogglebutton(app.TYPEOFANTENNAButtonGroup);
            app.CylindricalAntennaButton.Text = 'Cylindrical Antenna';
            app.DipoleVeeButton = uitogglebutton(app.TYPEOFANTENNAButtonGroup);
            app.DipoleVeeButton.Text = 'Dipole Vee';
            app.RectangularLoopButton = uitogglebutton(app.TYPEOFANTENNAButtonGroup);
            app.RectangularLoopButton.Text = 'Rectangular Loop';
            app.InputButtonGroup = uibuttongroup(app.UIFigure);
            app.InputButtonGroup.Title = 'Input';
            app.LengthincmEditFieldLabel = uilabel(app.InputButtonGroup);
            app.LengthincmEditFieldLabel.Text = 'Length (in cm)';
            app.LengthincmEditField = uieditfield(app.InputButtonGroup, 'numeric');
            app.WidthinmmEditFieldLabel = uilabel(app.InputButtonGroup);
            app.WidthinmmEditFieldLabel.Text = 'Width (in mm)';
            app.WidthinmmEditField = uieditfield(app.InputButtonGroup, 'numeric');
            app.FrequencyEditFieldLabel = uilabel(app.InputButtonGroup);
            app.FrequencyEditFieldLabel.Text = 'Frequency';
            app.FrequencyEditField = uieditfield(app.InputButtonGroup, 'numeric');
            app.DropDown = uidropdown(app.InputButtonGroup);
            app.DropDown.Items = {'Hz', 'KHz', 'MHz', 'GHz'};
            app.DropDown.Value = 'GHz';
            app.StartFrequencyEditFieldLabel = uilabel(app.InputButtonGroup);
            app.StartFrequencyEditFieldLabel.Text = 'Start Frequency';
            app.StartFrequencyEditField = uieditfield(app.InputButtonGroup, 'numeric');
            app.DropDown_2 = uidropdown(app.InputButtonGroup);
            app.DropDown_2.Items = {'Hz', 'KHz', 'MHz', 'GHz'};
            app.DropDown_2.Value = 'GHz';
            app.EndFrequencyEditFieldLabel = uilabel(app.InputButtonGroup);
            app.EndFrequencyEditFieldLabel.Text = 'End Frequency';
            app.EndFrequencyEditField = uieditfield(app.InputButtonGroup, 'numeric');
            app.DropDown_3 = uidropdown(app.InputButtonGroup);
            app.DropDown_3.Items = {'Hz', 'KHz', 'MHz', 'GHz'};
            app.DropDown_3.Value = 'GHz';
            app.OutputButtonGroup = uibuttongroup(app.UIFigure);
            app.OutputButtonGroup.Title = 'Output';
            app.ShowAzimuthPatternButton = uibutton(app.OutputButtonGroup, 'push');
            app.ShowAzimuthPatternButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAzimuthPatternButtonPushed, true);
            app.ShowAzimuthPatternButton.Text = 'Show Azimuth Pattern';
            app.EHfieldButton = uibutton(app.OutputButtonGroup, 'push');
            app.EHfieldButton.ButtonPushedFcn = createCallbackFcn(app, @EHfieldButtonPushed, true);
            app.EHfieldButton.Text = 'E&H field';
            app.ShowRadiationPatternButton = uibutton(app.OutputButtonGroup, 'push');
            app.ShowRadiationPatternButton.ButtonPushedFcn = createCallbackFcn(app, @ShowRadiationPatternButtonPushed, true);
            app.ShowRadiationPatternButton.Text = 'Show Radiation Pattern';
            app.ShowElevationPatternButton = uibutton(app.OutputButtonGroup, 'push');
            app.ShowElevationPatternButton.ButtonPushedFcn = createCallbackFcn(app, @ShowElevationPatternButtonPushed, true);
            app.ShowElevationPatternButton.Text = 'Show Elevation Pattern';
            app.VSWRButton = uibutton(app.OutputButtonGroup, 'push');
            app.VSWRButton.ButtonPushedFcn = createCallbackFcn(app, @VSWRButtonPushed, true);
            app.VSWRButton.Text = 'VSWR';
            app.HPBWButton = uibutton(app.OutputButtonGroup, 'push');
            app.HPBWButton.ButtonPushedFcn = createCallbackFcn(app, @HPBWButtonPushed, true);
            app.HPBWButton.Text = 'HPBW';
            app.ShowAntennaButton = uibutton(app.OutputButtonGroup, 'push');
            app.ShowAntennaButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAntennaButtonPushed, true);
            app.ShowAntennaButton.Text = 'Show Antenna';
            app.ProcessButton = uibutton(app.UIFigure, 'push');
            app.ProcessButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessButtonPushed, true);
            app.ProcessButton.Text = 'Process';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EE305_Group7_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end